classdef RobotArm6Dof < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        Label            matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Label_2          matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
        Label_3          matlab.ui.control.Label
        Slider_3         matlab.ui.control.Slider
        Label_4          matlab.ui.control.Label
        Slider_4         matlab.ui.control.Slider
        Panel_2          matlab.ui.container.Panel
        Label_5          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        Slider_5         matlab.ui.control.Slider
        Label_7          matlab.ui.control.Label
        Slider_6         matlab.ui.control.Slider
        XEditField       matlab.ui.control.NumericEditField
        XEditFieldLabel  matlab.ui.control.Label
        YEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.NumericEditField
        ZEditFieldLabel  matlab.ui.control.Label
        ZEditField       matlab.ui.control.NumericEditField
        Button_8         matlab.ui.control.Button
        COMPanel         matlab.ui.container.Panel
        DropDownLabel    matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Lamp             matlab.ui.control.Lamp
        UITable          matlab.ui.control.Table
        Button_3         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
    end

    properties (Access = private)
        arduinoSerial; % Description
        % инициализация сервоприводов
        th1Servo; 
        th2Servo;
        th3Servo;
        th4Servo;
        rotateServo;
        grabServo;
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % вывод доступных значений ком портов
            freeports = serialportlist("available");
            app.DropDown.Items = freeports
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try 
                app.arduinoSerial = arduino(string(app.DropDown.Items(app.DropDown.Value)), 'Uno', 'Libraries', 'Servo');
                app.th1Servo = servo(app.arduinoSerial, 'A0');
                app.th2Servo = servo(app.arduinoSerial, 'A1');
                app.th3Servo = servo(app.arduinoSerial, 'A2');
                app.th4Servo = servo(app.arduinoSerial, 'A3');
                app.rotateServo = servo(app.arduinoSerial, 'A4');
                app.grabServo = servo(app.arduinoSerial, 'A5');
                app.Lamp.Color = 'green';
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            try
                theta11 =  app.Slider.Value/180;
                writePosition(app.th1Servo, theta11);
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            try
                theta22 =  app.Slider_2.Value/180;
                writePosition(app.th2Servo, theta22);
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';  
            end    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            try
            theta33 =  app.Slider_3.Value/180
            writePosition(app.th3Servo, theta33);
            catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red'; 
            end 
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
           try
               theta44 =  app.Slider_4.Value/180
               writePosition(app.th4Servo, theta44);
           catch 
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red';
                pause(0.2);
                app.Lamp.Color = 'green';
                pause(0.2);
                app.Lamp.Color = 'red'; 
           end  
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            try 
              rotate =  app.Slider_5.Value/180
              writePosition(app.rotateServo, rotate);  
            catch 
              app.Lamp.Color = 'green';
              pause(0.2);
              app.Lamp.Color = 'red';
              pause(0.2);
              app.Lamp.Color = 'green';
              pause(0.2);
              app.Lamp.Color = 'red';   
            end  
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            try
              grab =  app.Slider_6.Value/180;
              writePosition(app.grabServo, grab);  
            catch 
              app.Lamp.Color = 'green';
              pause(0.2);
              app.Lamp.Color = 'red';
              pause(0.2);
              app.Lamp.Color = 'green';
              pause(0.2);
              app.Lamp.Color = 'red';  
            end     
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.arduinoSerial = ' ';
            app.th1Servo= ' ';
            app.th2Servo= ' ';
            app.th3Servo= ' ';
            app.th4Servo= ' ';
            app.rotateServo= ' ';
            app.grabServo= ' ';
            app.Lamp.Color = 'red';
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            Px = app.XEditField.Value
            Py = app.YEditField.Value
            Pz = app.ZEditField.Value
            
            L1 = Link([ 0 100 0 pi/2], 'standard');
            L2=Link([ 0 0 100 0], 'standard');
            L3=Link([ 0 0 85 0], 'standard');
            L4=Link([ 0 0 150 0], 'standard');
            
            robot=SerialLink([L1,L2,L3,L4]);
            
            T = [1 0 0 Px
                 0 1 0 Py
                 0 0 1 Pz
                 0 0 0 1];
            
            J = robot.ikine(T,'q', [0 pi/2 0 0 0],'mask',[1 1 1 0 0 1])*180/pi
            
            Th1 = J(1)+90
            Th2 = J(2)
            Th3 = J(3)+90
            Th4 = J(4)+90
             
            writePosition(app.th1Servo, Th1/180);
            writePosition(app.th2Servo, Th2/180);
            writePosition(app.th3Servo, Th3/180);
            writePosition(app.th4Servo, Th4/180);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            serv1 = readPosition(app.th1Servo)*180;
            serv2 = readPosition(app.th2Servo)*180;
            serv3 = readPosition(app.th3Servo)*180;
            serv4 = readPosition(app.th4Servo)*180;
            
            rotserv = readPosition(app.rotateServo)*180;
            grapserv = readPosition(app.grabServo)*180;
            app.UITable.Data(end+1,:) = [serv1 serv2 serv3 serv4 rotserv grapserv]; % Add Row of zeros
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            quantOfPoints = length(app.UITable.Data(:,1));
            for i = 1:1:quantOfPoints
                serv1 = app.UITable.Data(i,1);
                serv2 = app.UITable.Data(i,2);
                serv3 = app.UITable.Data(i,3);
                serv4 = app.UITable.Data(i,4);
                
                rotserv = app.UITable.Data(i,5);
                grapserv = app.UITable.Data(i,6);
                
                writePosition(app.th1Servo, serv1/180);
                writePosition(app.th2Servo, serv2/180);
                writePosition(app.th3Servo, serv3/180);
                writePosition(app.th4Servo, serv4/180);
                
                writePosition(app.rotateServo, rotserv/180);
                writePosition(app.grabServo, grapserv/180);
                pause(0.8);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.UITable.Data(end,:) = []; % Удалить строку
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.UITable.Data = [];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.arduinoSerial = ' ';
            app.th1Servo= ' ';
            app.th2Servo= ' ';
            app.th3Servo= ' ';
            app.th4Servo= ' ';
            app.rotateServo= ' ';
            app.grabServo= ' ';
            
            app.Lamp.Color = 'red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Управление';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'θ1';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 90;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'θ2';
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 90;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'θ3';
            app.Slider_3 = uislider(app.Panel);
            app.Slider_3.Limits = [0 180];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 90;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'θ4';
            app.Slider_4 = uislider(app.Panel);
            app.Slider_4.Limits = [0 180];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 90;
            app.Panel_2 = uipanel(app.Panel);
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = 'Выходное звено';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = 'Вращение';
            app.Slider_5 = uislider(app.Panel_2);
            app.Slider_5.Limits = [0 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Value = 90;
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = 'Захват';
            app.Slider_6 = uislider(app.Panel_2);
            app.Slider_6.Limits = [0 180];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 90;
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.XEditField.Value = 335;
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditField.Value = 100;
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'Ввод';
            app.COMPanel = uipanel(app.UIFigure);
            app.COMPanel.Title = 'Подключение к COM порту';
            app.DropDownLabel = uilabel(app.COMPanel);
            app.DropDownLabel.Text = 'Порт';
            app.DropDown = uidropdown(app.COMPanel);
            app.DropDown.Items = {};
            app.DropDown.ItemsData = [1 2 3 4];
            app.DropDown.Value = {};
            app.Button = uibutton(app.COMPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Подключиться';
            app.Button_2 = uibutton(app.COMPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Отключиться';
            app.Lamp = uilamp(app.COMPanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'θ1'; 'θ2'; 'θ3'; 'θ4'; 'Вращение'; 'Захват'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Запуск';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Остановка';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Очистить таблицу';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotArm6Dof

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end